classdef final_homework < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        SaveButton          matlab.ui.control.Button
        PlotButton          matlab.ui.control.Button
        CloseButton         matlab.ui.control.Button
        gridCheckBox        matlab.ui.control.CheckBox
        Panel_2             matlab.ui.container.Panel
        moveCheckBox        matlab.ui.control.CheckBox
        kSpinner            matlab.ui.control.Spinner
        kSpinnerLabel       matlab.ui.control.Label
        Button_7            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Slider              matlab.ui.control.Slider
        Panel               matlab.ui.container.Panel
        Button              matlab.ui.control.Button
        NEditField          matlab.ui.control.NumericEditField
        NEditFieldLabel     matlab.ui.control.Label
        Button_4            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        FsHzEditField       matlab.ui.control.NumericEditField
        FsHzEditFieldLabel  matlab.ui.control.Label
        DutyEditField       matlab.ui.control.NumericEditField
        DutyLabel           matlab.ui.control.Label
        F0HzEditField       matlab.ui.control.NumericEditField
        F0HzEditFieldLabel  matlab.ui.control.Label
        WaveDropDown        matlab.ui.control.DropDown
        WaveDropDownLabel   matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        xi2Button           matlab.ui.control.Button
        la2Button           matlab.ui.control.Button
        so2Button           matlab.ui.control.Button
        fa2Button           matlab.ui.control.Button
        mi2Button           matlab.ui.control.Button
        re2Button           matlab.ui.control.Button
        do2Button           matlab.ui.control.Button
        xiButton            matlab.ui.control.Button
        laButton            matlab.ui.control.Button
        soButton            matlab.ui.control.Button
        faButton            matlab.ui.control.Button
        miButton            matlab.ui.control.Button
        reButton            matlab.ui.control.Button
        doButton            matlab.ui.control.Button
        FourierLabel        matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
        Menu                matlab.ui.container.Menu
        saveMenu            matlab.ui.container.Menu
        loadMenu            matlab.ui.container.Menu
        Menu_2              matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)        
        saveApp
        loadApp
        filename = 'a.mat' %file name
        tr % data of raw wave t
        yr % data of raw wave y
        yr2
        tf % data of fourier wave t
        yf % data of fourier wave y
        a0
        ak
        bk
        pr % plot of raw wave
        pf % plot of fourier wave
        pn % plot of music note
    end
    
    methods (Access = private)
        function initialApp(app)
            f0 = app.F0HzEditField.Value;
            fs = app.FsHzEditField.Value;
            T = 1/f0;
            duty = app.DutyEditField.Value;
            option = app.WaveDropDown.Value;
            N = app.NEditField.Value;   
            
            [app.tr,app.yr,app.yr2] = chooseWave(fs,f0,duty,N,option);
            
            wave = getFunc(f0,duty,option);
            t = symvar(wave);
            [app.a0,app.ak,app.bk] = fourierCoe(wave,t,T);
                  
        end
        
        
        function pr = plotRaw(app)
            hold(app.UIAxes,'on')
            delete(app.pr);
            pr = plot(app.UIAxes,app.tr,app.yr,'k','linewidth',2); 
            hold(app.UIAxes,'off')
        end        
              
        function plotFourier(app)
            f0 = app.F0HzEditField.Value; 
            k = app.kSpinner.Value;
            w = 2*pi*f0;
            step = 1/app.FsHzEditField.Value;
            app.tf = 0:step:app.NEditField.Value/f0;
            isMove = app.moveCheckBox.Value;
            app.FourierLabel.Text= " ";
            
            hold(app.UIAxes,"on");
            delete(app.pf);
            app.yf = zeros(size(app.tf));
            app.yf = app.yf + double(app.a0)/2;%直流成分
            
            if isMove
                app.pf = plot(app.UIAxes, app.tf,app.yf,'r','linewidth',1.5);
                app.FourierLabel.Text = "当前谐波次数:0" ;
                for kk = 1 :1: k
                    if(kk<=15)
                        pause(0.5);
                    elseif(kk<30)
                        pause(0.3);
                    elseif(kk<60)
                        pause(0.15);
                    else
                        pause(0.08);
                    end
                    y_tmp = double(app.ak(kk))*cos(kk*w*app.tf)+double(app.bk(kk))*sin(kk*w*app.tf);
                    app.yf = app.yf + y_tmp;
                    app.pf.YData = app.yf;
                    app.FourierLabel.Text = sprintf("当前谐波次数:%d",round(kk));
                end
            else
                if k
                    for kk = 1 : 1 : k
                        y_tmp = double(app.ak(kk))*cos(kk*w*app.tf)+double(app.bk(kk))*sin(kk*w*app.tf);
                        app.yf = app.yf + y_tmp;                   
                    end
                end
                app.pf = plot(app.UIAxes, app.tf, app.yf,'r');            
                app.FourierLabel.Text= sprintf("当前谐波次数:%d",round(k));
            end
            
            hold(app.UIAxes,"off");
        end


        function note(app,option)
            
            [t,note,fs] = music(option);
            delete(app.pr);
            delete(app.pf);
            app.FourierLabel.Text = " ";
            delete(app.pn);
            hold(app.UIAxes,'on');
            app.pn = plot(app.UIAxes, t,note);
            hold(app.UIAxes,'off');
            sound(note,fs);
        end
    end
    
    methods (Access = public)
        
        function saveData(app,filename)
            app.filename = filename;
            data.rawExist = ishandle(app.pr);           
            data.rawX = app.tr;
            data.rawY = app.yr;
            data.rawWave = app.yr2;
            
            data.fs = app.FsHzEditField.Value;
            data.f0 = app.F0HzEditField.Value;
            data.circle = app.NEditField.Value;
            data.duty = app.DutyEditField.Value;
            data.wave.typeName = app.WaveDropDown.Items{app.WaveDropDown.Value};
            data.wave.value = app.WaveDropDown.Value;
            
            data.fouierExist = ishandle(app.pf);            
            data.fourierX = app.tf;
            data.fourierY = app.yf;
            data.fourierK = app.kSpinner.Value;
            
            data.a0 = app.a0;
            data.ak = app.ak;
            data.bk = app.bk;
            
            save(filename,'data');
            
            app.SaveButton.Enable = 'on';
        end
        
        function loadData(app,filename)
            load(filename,'-mat','data');
            app.filename = filename;
            app.tr = data.rawX;
            app.yr = data.rawY;
            app.yr2 = data.rawWave;
            app.FsHzEditField.Value = data.fs;
            app.F0HzEditField.Value = data.f0;
            app.NEditField.Value  = data.circle;
            app.DutyEditField.Value = data.duty;
            app.WaveDropDown.Value = data.wave.value;
            app.tf = data.fourierX;
            app.yf =data.fourierY;
            app.kSpinner.Value = data.fourierK;
            app.Slider.Value = data.fourierK;
            app.moveCheckBox.Value = 0;
            app.gridCheckBox.Value = 0;
            
            app.a0 = data.a0;
            app.ak = data.ak;
            app.bk = data.bk;
            
            if(data.rawExist)
                app.pr = plotRaw(app);

            end
            if(data.fouierExist)
                hold(app.UIAxes,"on");
                app.pf = plot(app.UIAxes, app.tf, app.yf,'r');            
                app.FourierLabel.Text= sprintf("当前谐波次数:%d",data.fourierK);
                hold(app.UIAxes,"off");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initialApp(app);
            app.pr = plotRaw(app);
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            value = app.kSpinner.Value; % get the slider value
            
            switch key
                case {'leftarrow','downarrow'}
                    value = value-1; % left or down value
                    app.kSpinner.Value = value; % set the slider value
                    kSpinnerValueChanged(app,event);
                case {'rightarrow','uparrow'}
                    value = value+1;
                    app.kSpinner.Value = value; % set the slider value
                    kSpinnerValueChanged(app,event);
                case '1'
                    doButtonPushed(app,event);
                case '2'
                    reButtonPushed(app,event);
                case '3'
                    miButtonPushed(app,event);
                case '4'
                    faButtonPushed(app,event);
                case '5'
                    soButtonPushed(app,event);
                case '6'
                    laButtonPushed(app,event);
                case '7'
                    xiButtonPushed(app,event);
                case 'q'
                    do2ButtonPushed(app,event);
                case 'w'
                    re2ButtonPushed(app,event);
                case 'e'
                    mi2ButtonPushed(app,event);
                case 'r'
                    fa2ButtonPushed(app,event);
                case 't'
                    so2ButtonPushed(app,event);
                case 'y'
                    la2ButtonPushed(app,event);
                case 'u'
                    xi2ButtonPushed(app,event);
                
            end
        end

        % Value changed function: WaveDropDown
        function WaveDropDownValueChanged(app, event)
            initialApp(app);
            app.pr = plotRaw(app);
        end

        % Value changed function: F0HzEditField
        function F0HzEditFieldValueChanged(app, event)
            if(app.F0HzEditField.Value>0)
                initialApp(app);
            else
                msgbox('基波频率要大于0','错误','error')
            end

        end

        % Value changed function: DutyEditField
        function DutyEditFieldValueChanged(app, event)
            value = app.DutyEditField.Value;
            if(value>=0 || value<=100)
                initialApp(app);
            else
                msgbox('占空比要在0到100之间','错误','error')
            end
            
        end

        % Value changed function: FsHzEditField
        function FsHzEditFieldValueChanged(app, event)
            value = app.FsHzEditField.Value;
            if(value>0)
                if(value/app.F0HzEditField.Value >=200)
                    initialApp(app);
                else
                    msgbox('采样频率可能过低','警告','warn')
                end
            else
                msgbox('采样频率要大于0','错误','error')
            end
            
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            if(value>=0)
                initialApp(app);
            else
                msgbox('周期数不能为负数','错误','error')
            end            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {515, 515};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {246, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.pn);
            app.pr = plotRaw(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.kSpinner.Value = round(value);
        end

        % Value changed function: kSpinner
        function kSpinnerValueChanged(app, event)
            value = app.kSpinner.Value;
            app.Slider.Value = round(value);
        end

        % Value changed function: gridCheckBox
        function gridCheckBoxValueChanged(app, event)
            if app.gridCheckBox.Value
                grid(app.UIAxes,"on");
            else
                grid(app.UIAxes,"off");
            end
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            
            delete(app.pr);
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            delete(app.pn);
            plotFourier(app);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            
            delete(app.pf);
            app.FourierLabel.Text = " ";
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.pr = plotRaw(app);
            plotFourier(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            choice = questdlg("确定关闭吗？","关闭","YES","NO","NO");
            switch choice
                case "YES"
                    delete(app.UIFigure); %关闭所有窗口
                    return;
                case "NO"
                    return;
            end          
                        
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            sound(app.yr2,app.FsHzEditField.Value);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            sound(app.yf,app.FsHzEditField.Value);
        end

        % Button pushed function: doButton
        function doButtonPushed(app, event)
            note(app,1)
        end

        % Button pushed function: reButton
        function reButtonPushed(app, event)
            note(app,2)
        end

        % Button pushed function: miButton
        function miButtonPushed(app, event)
            note(app,3);
        end

        % Button pushed function: faButton
        function faButtonPushed(app, event)
            note(app,4);
        end

        % Button pushed function: soButton
        function soButtonPushed(app, event)
            note(app,5);
        end

        % Button pushed function: laButton
        function laButtonPushed(app, event)
            note(app,6);
        end

        % Button pushed function: xiButton
        function xiButtonPushed(app, event)
            note(app,7);
        end

        % Button pushed function: do2Button
        function do2ButtonPushed(app, event)
            note(app,8);

        end

        % Button pushed function: re2Button
        function re2ButtonPushed(app, event)
            note(app,9);

        end

        % Button pushed function: mi2Button
        function mi2ButtonPushed(app, event)
            note(app,10);
        end

        % Button pushed function: fa2Button
        function fa2ButtonPushed(app, event)
            note(app,11);
        end

        % Button pushed function: so2Button
        function so2ButtonPushed(app, event)
            note(app,12);
        end

        % Button pushed function: la2Button
        function la2ButtonPushed(app, event)
            note(app,13);
        end

        % Button pushed function: xi2Button
        function xi2ButtonPushed(app, event)
            note(app,14);
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            msgbox(["姓名：王润泽","学号：pb20020480","学院：物理学院"]);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.Enable = 'off';
            app.saveApp = output_data(app,app.filename);
        end

        % Menu selected function: saveMenu
        function saveMenuSelected(app, event)
            app.saveApp = output_data(app,app.filename);
        end

        % Menu selected function: loadMenu
        function loadMenuSelected(app, event)
            app.loadApp = input_data(app,app.filename);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.saveApp);
            delete(app.loadApp);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '时域参数';
            app.WaveDropDownLabel = uilabel(app.Panel);
            app.WaveDropDownLabel.Text = '波形Wave';
            app.WaveDropDown = uidropdown(app.Panel);
            app.WaveDropDown.Items = {'Triangle', 'Square', 'Sawtooth'};
            app.WaveDropDown.ItemsData = [1 2 3];
            app.WaveDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveDropDownValueChanged, true);
            app.WaveDropDown.Value = 1;
            app.F0HzEditFieldLabel = uilabel(app.Panel);
            app.F0HzEditFieldLabel.Text = '基波频率F0/Hz';
            app.F0HzEditField = uieditfield(app.Panel, 'numeric');
            app.F0HzEditField.ValueChangedFcn = createCallbackFcn(app, @F0HzEditFieldValueChanged, true);
            app.F0HzEditField.Tooltip = {'数值大于0'};
            app.F0HzEditField.Value = 1;
            app.DutyLabel = uilabel(app.Panel);
            app.DutyLabel.Text = '占空比Duty/%';
            app.DutyEditField = uieditfield(app.Panel, 'numeric');
            app.DutyEditField.ValueChangedFcn = createCallbackFcn(app, @DutyEditFieldValueChanged, true);
            app.DutyEditField.Tooltip = {'数值范围：0~100'};
            app.DutyEditField.Value = 50;
            app.FsHzEditFieldLabel = uilabel(app.Panel);
            app.FsHzEditFieldLabel.Text = '采样频率Fs/Hz';
            app.FsHzEditField = uieditfield(app.Panel, 'numeric');
            app.FsHzEditField.ValueChangedFcn = createCallbackFcn(app, @FsHzEditFieldValueChanged, true);
            app.FsHzEditField.Tooltip = {'数值大于0'};
            app.FsHzEditField.Value = 8800;
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '绘制波形';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '隐藏波形';
            app.NEditFieldLabel = uilabel(app.Panel);
            app.NEditFieldLabel.Text = '周期数N';
            app.NEditField = uieditfield(app.Panel, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Tooltip = {'数值大于0'};
            app.NEditField.Value = 5;
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '播放波形';
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '合成参数';
            app.Slider = uislider(app.Panel_2);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '绘制波形';
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '隐藏波形';
            app.Button_7 = uibutton(app.Panel_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '播放波形';
            app.kSpinnerLabel = uilabel(app.Panel_2);
            app.kSpinnerLabel.Text = '合成谐波次数k：';
            app.kSpinner = uispinner(app.Panel_2);
            app.kSpinner.ValueChangedFcn = createCallbackFcn(app, @kSpinnerValueChanged, true);
            app.moveCheckBox = uicheckbox(app.Panel_2);
            app.moveCheckBox.Text = '动态显示';
            app.gridCheckBox = uicheckbox(app.LeftPanel);
            app.gridCheckBox.ValueChangedFcn = createCallbackFcn(app, @gridCheckBoxValueChanged, true);
            app.gridCheckBox.Text = '网格';
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = '关闭';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = '绘图';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = '保存';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '波形')
            xlabel(app.UIAxes, 't')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-0.2 1.2];
            app.FourierLabel = uilabel(app.RightPanel);
            app.FourierLabel.Text = '';
            app.doButton = uibutton(app.RightPanel, 'push');
            app.doButton.ButtonPushedFcn = createCallbackFcn(app, @doButtonPushed, true);
            app.doButton.Text = 'do';
            app.reButton = uibutton(app.RightPanel, 'push');
            app.reButton.ButtonPushedFcn = createCallbackFcn(app, @reButtonPushed, true);
            app.reButton.Text = 're';
            app.miButton = uibutton(app.RightPanel, 'push');
            app.miButton.ButtonPushedFcn = createCallbackFcn(app, @miButtonPushed, true);
            app.miButton.Text = 'mi';
            app.faButton = uibutton(app.RightPanel, 'push');
            app.faButton.ButtonPushedFcn = createCallbackFcn(app, @faButtonPushed, true);
            app.faButton.Text = 'fa';
            app.soButton = uibutton(app.RightPanel, 'push');
            app.soButton.ButtonPushedFcn = createCallbackFcn(app, @soButtonPushed, true);
            app.soButton.Text = 'so';
            app.laButton = uibutton(app.RightPanel, 'push');
            app.laButton.ButtonPushedFcn = createCallbackFcn(app, @laButtonPushed, true);
            app.laButton.Text = 'la';
            app.xiButton = uibutton(app.RightPanel, 'push');
            app.xiButton.ButtonPushedFcn = createCallbackFcn(app, @xiButtonPushed, true);
            app.xiButton.Text = 'xi';
            app.do2Button = uibutton(app.RightPanel, 'push');
            app.do2Button.ButtonPushedFcn = createCallbackFcn(app, @do2ButtonPushed, true);
            app.do2Button.Text = 'do2';
            app.re2Button = uibutton(app.RightPanel, 'push');
            app.re2Button.ButtonPushedFcn = createCallbackFcn(app, @re2ButtonPushed, true);
            app.re2Button.Text = 're2';
            app.mi2Button = uibutton(app.RightPanel, 'push');
            app.mi2Button.ButtonPushedFcn = createCallbackFcn(app, @mi2ButtonPushed, true);
            app.mi2Button.Text = 'mi2';
            app.fa2Button = uibutton(app.RightPanel, 'push');
            app.fa2Button.ButtonPushedFcn = createCallbackFcn(app, @fa2ButtonPushed, true);
            app.fa2Button.Text = 'fa2';
            app.so2Button = uibutton(app.RightPanel, 'push');
            app.so2Button.ButtonPushedFcn = createCallbackFcn(app, @so2ButtonPushed, true);
            app.so2Button.Text = 'so2';
            app.la2Button = uibutton(app.RightPanel, 'push');
            app.la2Button.ButtonPushedFcn = createCallbackFcn(app, @la2ButtonPushed, true);
            app.la2Button.Text = 'la2';
            app.xi2Button = uibutton(app.RightPanel, 'push');
            app.xi2Button.ButtonPushedFcn = createCallbackFcn(app, @xi2ButtonPushed, true);
            app.xi2Button.Text = 'xi2';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.saveMenu = uimenu(app.Menu);
            app.saveMenu.MenuSelectedFcn = createCallbackFcn(app, @saveMenuSelected, true);
            app.saveMenu.Text = '导出';
            app.loadMenu = uimenu(app.Menu);
            app.loadMenu.MenuSelectedFcn = createCallbackFcn(app, @loadMenuSelected, true);
            app.loadMenu.Text = '导入';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '关于';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_homework

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end