classdef input_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Label      matlab.ui.control.Label
        EditField  matlab.ui.control.EditField
        Button     matlab.ui.control.Button
        Button_2   matlab.ui.control.Button
        Button_3   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, filename)
            app.CallingApp = mainapp;
            app.EditField.Value = filename;           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            name =  app.EditField.Value;            
            if(exist(name,'file'))
                loadData(app.CallingApp,name);
                delete(app);
                return;
            else
                msgbox('该文件不存在,请重新输入!','错误','error');
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            fileFolder = pwd;
            dirOutput=dir(fullfile(fileFolder,'*.mat'));
            fileNames={dirOutput.name};
            msgbox(fileNames);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '查询';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '取消';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '加载文件名为：';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = input_data(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end