classdef homework5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        Panel_2           matlab.ui.container.Panel
        Button            matlab.ui.control.Button
        CheckBox          matlab.ui.control.CheckBox
        Slider            matlab.ui.control.Slider
        Label             matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        MEditField        matlab.ui.control.NumericEditField
        MEditFieldLabel   matlab.ui.control.Label
        FsEditField       matlab.ui.control.NumericEditField
        FsEditFieldLabel  matlab.ui.control.Label
        T1EditField       matlab.ui.control.NumericEditField
        T1EditFieldLabel  matlab.ui.control.Label
        T0EditField       matlab.ui.control.NumericEditField
        T0EditFieldLabel  matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        FourierLabel      matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        ResultLabel       matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function triangle(app)
            app.Label_2.Text = " ";
            fs = app.FsEditField.Value;
            T1 = app.T1EditField.Value;
            T0 = app.T0EditField.Value;
            N = app.Slider.Value;
            M = app.MEditField.Value;
            if (T0 <=0) || (T1<=0) || (fs <= 0) || M <= 0
                app.Label_2.Text="错误：输入应该大于0";
                return
            end
            if T1*2>T0
                app.Label_2.Text="错误：基波周期与脉冲时长不匹配";
                return
            end
            
            t_max = T0*M;
            app.UIAxes.XLim = [0 t_max];
            w = 2*pi/T0;
            p_t = zeros(3*M+1);
            for k = 1:M
                p_t(3*k -1) = T0*(k-1) + T1;
                p_t(3*k) = T0*(k-1) + T0 -T1;
                p_t(3*k + 1) = T0*k ;
            end
            p_a = [1, 0, 0];
            p_amp = repmat(p_a,1,M);
            p_amp = [p_amp,1];
            t = 0:1/fs:t_max;
            
            %sinx/x
            Sa = @(x) sin(x)/x;
            
            %Fourier级数
            a0 = T1/T0;
            ak = @(k) T1/T0*(Sa(k*w*T1/2))^2;
            
            
            rp = plot(app.UIAxes,p_t, p_amp,'k','linewidth',2);
            
            hold(app.UIAxes,"on");
            x_syn = zeros(size(t));
            x_syn = x_syn + a0;%直流成分
            if app.CheckBox.Value
                h_syn = plot(app.UIAxes, t,x_syn,'r','linewidth',1.5);
                legend(app.UIAxes,h_syn,'fourierN');
                title(app.UIAxes,"当前谐波次数:0");
                for k = 1 :1: N
                    pause(0.5);
                    x_tmp = 2*ak(k)*cos(k*w*t);
                    x_syn = x_syn + x_tmp;
                    h_syn.YData = x_syn;
                    title(app.UIAxes,sprintf("当前谐波次数:%d",round(k)));
                    legend(app.UIAxes,h_syn,'fourierN');

                end
            else
                if N
                    for k = 1 : 1 : N
                        x_tmp = 2*ak(k)*cos(k*w*t);
                        x_syn = x_syn + x_tmp;                    
                    end
                end
                h_syn = plot(app.UIAxes, t, x_syn,'r','linewidth',1.5);            
                legend(app.UIAxes,h_syn,'fourierN');
                title(app.UIAxes,sprintf("当前谐波次数:%d",round(N)));
            end
                                    
            app.Label_2.Text = "拟合成功！";
            hold(app.UIAxes,"off");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            triangle(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            triangle(app);
        end

        % Value changed function: FsEditField
        function FsEditFieldValueChanged(app, event)
%             value = app.FsEditField.Value;
            triangle(app);
        end

        % Value changed function: T1EditField
        function T1EditFieldValueChanged(app, event)
%             value = app.T1EditField.Value;
            triangle(app);
        end

        % Value changed function: T0EditField
        function T0EditFieldValueChanged(app, event)
%             value = app.T0EditField.Value;
            triangle(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
%             value = app.Slider.Value;
            triangle(app);
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
%             value = app.MEditField.Value;
            triangle(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '时域参数';
            app.T0EditFieldLabel = uilabel(app.Panel);
            app.T0EditFieldLabel.Text = '基波周期T0';
            app.T0EditField = uieditfield(app.Panel, 'numeric');
            app.T0EditField.ValueChangedFcn = createCallbackFcn(app, @T0EditFieldValueChanged, true);
            app.T0EditField.Value = 3;
            app.T1EditFieldLabel = uilabel(app.Panel);
            app.T1EditFieldLabel.Text = '脉冲时长T1';
            app.T1EditField = uieditfield(app.Panel, 'numeric');
            app.T1EditField.ValueChangedFcn = createCallbackFcn(app, @T1EditFieldValueChanged, true);
            app.T1EditField.Value = 1;
            app.FsEditFieldLabel = uilabel(app.Panel);
            app.FsEditFieldLabel.Text = '采样频率Fs';
            app.FsEditField = uieditfield(app.Panel, 'numeric');
            app.FsEditField.ValueChangedFcn = createCallbackFcn(app, @FsEditFieldValueChanged, true);
            app.FsEditField.Value = 10;
            app.MEditFieldLabel = uilabel(app.Panel);
            app.MEditFieldLabel.Text = '波周期数M';
            app.MEditField = uieditfield(app.Panel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 3;
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '合成参数';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '谐波次数';
            app.Slider = uislider(app.Panel_2);
            app.Slider.Limits = [0 50];
            app.Slider.MajorTicks = [0 10 20 30 40 50];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 2;
            app.CheckBox = uicheckbox(app.Panel_2);
            app.CheckBox.Text = '动态显示';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '绘图';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 't')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 9];
            app.UIAxes.YLim = [-0.1 1.5];
            app.UIAxes.YTick = [0 0.5 1 1.5];
            app.ResultLabel = uilabel(app.RightPanel);
            app.ResultLabel.Text = 'Result：';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '';
            app.FourierLabel = uilabel(app.RightPanel);
            app.FourierLabel.Text = '三角波周期信号的Fourier合成';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = homework5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end